classdef window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        RunMenu                         matlab.ui.container.Menu
        BasicPanel                      matlab.ui.container.Panel
        tendEditFieldLabel              matlab.ui.control.Label
        tendEditField                   matlab.ui.control.NumericEditField
        nIncrEditFieldLabel             matlab.ui.control.Label
        nIncrEditField                  matlab.ui.control.NumericEditField
        ParallelizationCheckBox         matlab.ui.control.CheckBox
        maxIterEditFieldLabel           matlab.ui.control.Label
        maxIterEditField                matlab.ui.control.NumericEditField
        CellsinitialgeometryDropDownLabel  matlab.ui.control.Label
        CellsinitialgeometryDropDown    matlab.ui.control.DropDown
        VolumeenergyPanel               matlab.ui.container.Panel
        lambdaVEditFieldLabel           matlab.ui.control.Label
        lambdaVEditField                matlab.ui.control.NumericEditField
        SurfaceenergyPanel              matlab.ui.container.Panel
        lambdaS1EditFieldLabel          matlab.ui.control.Label
        lambdaS1EditField               matlab.ui.control.NumericEditField
        lambdaS2EditFieldLabel          matlab.ui.control.Label
        lambdaS2EditField               matlab.ui.control.NumericEditField
        lambdaS3EditFieldLabel          matlab.ui.control.Label
        lambdaS3EditField               matlab.ui.control.NumericEditField
        SurfacetypeDropDownLabel        matlab.ui.control.Label
        SurfacetypeDropDown             matlab.ui.control.DropDown
        BendingenergyPanel              matlab.ui.container.Panel
        BendingSwitch                   matlab.ui.control.Switch
        lambdaBendEditFieldLabel        matlab.ui.control.Label
        lambdaBendEditField             matlab.ui.control.NumericEditField
        BendingAreaDependentCheckBox    matlab.ui.control.CheckBox
        EnergybarrierPanel              matlab.ui.container.Panel
        EnergyBarrierSwitch             matlab.ui.control.Switch
        lambdaBEditFieldLabel           matlab.ui.control.Label
        lambdaBEditField                matlab.ui.control.NumericEditField
        BetaEditFieldLabel              matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        BarrierTri0EditFieldLabel       matlab.ui.control.Label
        BarrierTri0EditField            matlab.ui.control.NumericEditField
        RemodellingPanel                matlab.ui.container.Panel
        RemodellingSwitch               matlab.ui.control.Switch
        RemodelTolEditFieldLabel        matlab.ui.control.Label
        RemodelTolEditField             matlab.ui.control.NumericEditField
        RemodelingFrequencyEditFieldLabel  matlab.ui.control.Label
        RemodelingFrequencyEditField    matlab.ui.control.NumericEditField
        CellAblationPanel               matlab.ui.container.Panel
        ContractilityenergyPanel        matlab.ui.container.Panel
        ContractilitySwitch             matlab.ui.control.Switch
        cContractilityEditFieldLabel    matlab.ui.control.Label
        cContractilityEditField         matlab.ui.control.NumericEditField
        ContractilitytypeDropDownLabel  matlab.ui.control.Label
        ContractilitytypeDropDown       matlab.ui.control.DropDown
        AblationSwitch                  matlab.ui.control.Switch
        TAblationEditFieldLabel         matlab.ui.control.Label
        TAblationEditField              matlab.ui.control.NumericEditField
        cellsToAblateEditFieldLabel     matlab.ui.control.Label
        cellsToAblateEditField          matlab.ui.control.NumericEditField
        ViscosityPanel                  matlab.ui.container.Panel
        nuEditFieldLabel                matlab.ui.control.Label
        nuEditField                     matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            clear
            clc
            
            addpath(strcat(pwd,Esc,'Geo'));
            addpath(strcat(pwd,Esc,'Build'));
            addpath(strcat(pwd,Esc,'Utilities'));
            addpath(strcat(pwd,Esc,'Remodel'));
            addpath(strcat(pwd,Esc,'PostProcessing'));
            addpath(strcat(pwd,Esc,'Kg'));
        end

        % Menu selected function: RunMenu
        function RunMenuSelected(app, event)


            %% ----- Basics ----- %%
            % Geometry
            if isequal(app.CellsinitialgeometryDropDown.Value, '3 x 1')
                Set.e = 1;
            elseif isequal(app.CellsinitialgeometryDropDown.Value, '3 x 3')
                Set.e = 4;
            end
            % Tuning parameters
            Set.s=1.5;
            Set.f=Set.s/2;
            
            % Time
            Set.tend=app.tendEditField.Value; % total simulation time
            Set.Nincr=app.nIncrEditField.Value; % number of time increments
            
            % Solution
            Set.MaxIter = app.maxIterEditField.Value;
            Set.Parallel = app.ParallelizationCheckBox.Value;
            
            %%  MECHANICS
            %---------- Volume
            Set.lambdaV=app.lambdaVEditField.Value;
            
            %---------- Surface
            if isequal(app.SurfacetypeDropDown.Value, 'Cell area')
                Set.SurfaceType=1;
            elseif isequal(app.SurfacetypeDropDown.Value, 'Face area')
                Set.SurfaceType=2;
            elseif isequal(app.SurfacetypeDropDown.Value, 'Cell area differential adhesion')
                Set.SurfaceType=4;
            end
            
            Set.lambdaS1 = app.lambdaS1EditField.Value;
            Set.lambdaS2 = app.lambdaS2EditField.Value;
            Set.lambdaS3 = app.lambdaS3EditField.Value;
            
            %---------- Energy barrier
            Set.EnergyBarrier=isequal(app.EnergyBarrierSwitch.Value, 'On');
            
            Set.lambdaB = app.lambdaBEditField.Value;
            Set.Beta = app.BetaEditField.Value;
            Set.BarrierTri0 = app.BarrierTri0EditField.Value;
            
            %---------- Bending
            Set.Bending = isequal(app.BendingSwitch.Value, 'On');
            Set.BendingAreaDependent = app.BendingAreaDependentCheckBox.Value;
            Set.lambdaBend = app.lambdaBendEditField.Value;
            
            %---------- Viscosity
            Set.nu = app.nuEditField.Value;
            
            %---------- Remodelling
            Set.Remodelling = isequal(app.RemodellingSwitch.Value, 'On');
            Set.RemodelTol = app.RemodelTolEditField.Value;
            Set.RemodelingFrequency = app.RemodelingFrequencyEditField.Value;
            
            
            %% CELL ABLATION
            %---------- Ablation properties
            Set.Ablation = isequal(app.AblationSwitch.Value, 'On');
            
            Set.TAblation = app.TAblationEditField.Value;
            Set.cellsToAblate = app.cellsToAblateEditField.Value;
            
            %---------- Contractility
            if isequal(app.ContractilitySwitch.Value, 'On')
                Set.cContractility = app.cContractilityEditField.Value;
                if isequal(app.ContractilitytypeDropDown.Value, 'Edge length')
                    Set.Contractility = 0;
                elseif isequal(app.ContractilitytypeDropDown.Value, 'Edge length and angle')
                    Set.Contractility = 1;
                elseif isequal(app.ContractilitytypeDropDown.Value, 'Edge length, angle and adjacent triangles')
                    Set.Contractility = 2;
                end
            else
                Set.cContractility = 0;
            end
            
            
            Main
        end

        % Value changed function: RemodellingSwitch
        function RemodellingSwitchValueChanged(app, event)
            value = lower(app.RemodellingSwitch.Value);
            app.RemodelTolEditField.Enable = value;
            app.RemodelingFrequencyEditField.Enable = value;
            app.RemodelTolEditFieldLabel.Enable = value;
            app.RemodelingFrequencyEditFieldLabel.Enable = value;
        end

        % Callback function
        function VolumeSwitchValueChanged(app, event)
            value = lower(app.VolumeSwitch.Value);
            app.lambdaVEditField.Enable = value;
            app.lambdaVEditFieldLabel.Enable = value;
        end

        % Callback function
        function SurfaceSwitchValueChanged(app, event)
            value = lower(app.SurfaceSwitch.Value);
            app.SurfacetypeDropDown.Enable = value;
            app.SurfacetypeDropDownLabel.Enable = value;
        end

        % Value changed function: ContractilitySwitch
        function ContractilitySwitchValueChanged(app, event)
            value = lower(app.ContractilitySwitch.Value);
            app.cContractilityEditField.Enable = value;
            app.cContractilityEditFieldLabel.Enable = value;
            app.ContractilitytypeDropDown.Enable = value;
            app.ContractilitytypeDropDownLabel.Enable = value;
        end

        % Value changed function: BendingSwitch
        function BendingSwitchValueChanged(app, event)
            value = lower(app.BendingSwitch.Value);
            
            app.BendingAreaDependentCheckBox.Enable = value;
            app.lambdaBendEditField.Enable = value;
            app.lambdaBendEditFieldLabel.Enable = value;
        end

        % Value changed function: EnergyBarrierSwitch
        function EnergyBarrierSwitchValueChanged(app, event)
            value = lower(app.EnergyBarrierSwitch.Value);
            
            app.lambdaBEditField.Enable = value;
            app.lambdaBEditFieldLabel.Enable = value;
            app.BetaEditField.Enable = value;
            app.BetaEditFieldLabel.Enable = value;
            app.BarrierTri0EditField.Enable = value;
            app.BarrierTri0EditFieldLabel.Enable = value;
        end

        % Value changed function: SurfacetypeDropDown
        function SurfacetypeDropDownValueChanged(app, event)
            value = app.SurfacetypeDropDown.Value;
            
            if isequal(value, 'Cell area')
                app.lambdaS1EditField.Enable = true;
                app.lambdaS1EditFieldLabel.Enable = true;
                app.lambdaS2EditField.Enable = false;
                app.lambdaS2EditFieldLabel.Enable = false;
                app.lambdaS3EditField.Enable = false;
                app.lambdaS3EditFieldLabel.Enable = false;
            elseif isequal(value, 'Face area')
                app.lambdaS1EditField.Enable = true;
                app.lambdaS1EditFieldLabel.Enable = true;
                app.lambdaS2EditField.Enable = false;
                app.lambdaS2EditFieldLabel.Enable = false;
                app.lambdaS3EditField.Enable = false;
                app.lambdaS3EditFieldLabel.Enable = false;
            elseif isequal(value, 'Cell area differential adhesion')
                app.lambdaS1EditField.Enable = true;
                app.lambdaS1EditFieldLabel.Enable = true;
                app.lambdaS2EditField.Enable = true;
                app.lambdaS2EditFieldLabel.Enable = true;
                app.lambdaS3EditField.Enable = true;
                app.lambdaS3EditFieldLabel.Enable = true;
            end
        end

        % Value changed function: AblationSwitch
        function AblationSwitchValueChanged(app, event)
            value = lower(app.AblationSwitch.Value);
            app.ContractilitySwitch.Enable = value;
            app.TAblationEditField.Enable = value;
            app.TAblationEditFieldLabel.Enable = value;
            app.cellsToAblateEditField.Enable = value;
            app.cellsToAblateEditFieldLabel.Enable = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.RunMenu = uimenu(app.Menu);
            app.RunMenu.MenuSelectedFcn = createCallbackFcn(app, @RunMenuSelected, true);
            app.RunMenu.Text = 'Run';
            app.BasicPanel = uipanel(app.UIFigure);
            app.BasicPanel.Title = 'Basic';
            app.tendEditFieldLabel = uilabel(app.BasicPanel);
            app.tendEditFieldLabel.Text = 'tend';
            app.tendEditField = uieditfield(app.BasicPanel, 'numeric');
            app.tendEditField.Limits = [1 Inf];
            app.tendEditField.Value = 300;
            app.nIncrEditFieldLabel = uilabel(app.BasicPanel);
            app.nIncrEditFieldLabel.Text = 'nIncr';
            app.nIncrEditField = uieditfield(app.BasicPanel, 'numeric');
            app.nIncrEditField.Limits = [1 Inf];
            app.nIncrEditField.Value = 300;
            app.ParallelizationCheckBox = uicheckbox(app.BasicPanel);
            app.ParallelizationCheckBox.Text = 'Parallelization';
            app.maxIterEditFieldLabel = uilabel(app.BasicPanel);
            app.maxIterEditFieldLabel.Text = 'maxIter';
            app.maxIterEditField = uieditfield(app.BasicPanel, 'numeric');
            app.maxIterEditField.Limits = [1 Inf];
            app.maxIterEditField.Value = 200;
            app.CellsinitialgeometryDropDownLabel = uilabel(app.BasicPanel);
            app.CellsinitialgeometryDropDownLabel.Text = 'Cells initial geometry';
            app.CellsinitialgeometryDropDown = uidropdown(app.BasicPanel);
            app.CellsinitialgeometryDropDown.Items = {'3 x 1', '3 x 3'};
            app.CellsinitialgeometryDropDown.Value = '3 x 1';
            app.VolumeenergyPanel = uipanel(app.UIFigure);
            app.VolumeenergyPanel.Title = 'Volume energy';
            app.lambdaVEditFieldLabel = uilabel(app.VolumeenergyPanel);
            app.lambdaVEditFieldLabel.Text = 'lambdaV';
            app.lambdaVEditField = uieditfield(app.VolumeenergyPanel, 'numeric');
            app.lambdaVEditField.Value = 5;
            app.SurfaceenergyPanel = uipanel(app.UIFigure);
            app.SurfaceenergyPanel.Title = 'Surface energy';
            app.lambdaS1EditFieldLabel = uilabel(app.SurfaceenergyPanel);
            app.lambdaS1EditFieldLabel.Text = 'lambdaS1';
            app.lambdaS1EditField = uieditfield(app.SurfaceenergyPanel, 'numeric');
            app.lambdaS1EditField.Value = 1;
            app.lambdaS2EditFieldLabel = uilabel(app.SurfaceenergyPanel);
            app.lambdaS2EditFieldLabel.Text = 'lambdaS2';
            app.lambdaS2EditField = uieditfield(app.SurfaceenergyPanel, 'numeric');
            app.lambdaS2EditField.Value = 0.5;
            app.lambdaS3EditFieldLabel = uilabel(app.SurfaceenergyPanel);
            app.lambdaS3EditFieldLabel.Text = 'lambdaS3';
            app.lambdaS3EditField = uieditfield(app.SurfaceenergyPanel, 'numeric');
            app.lambdaS3EditField.Value = 0.5;
            app.SurfacetypeDropDownLabel = uilabel(app.SurfaceenergyPanel);
            app.SurfacetypeDropDownLabel.Text = 'Surface type';
            app.SurfacetypeDropDown = uidropdown(app.SurfaceenergyPanel);
            app.SurfacetypeDropDown.Items = {'Cell area', 'Face area', 'Cell area differential adhesion'};
            app.SurfacetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SurfacetypeDropDownValueChanged, true);
            app.SurfacetypeDropDown.Value = 'Cell area differential adhesion';
            app.BendingenergyPanel = uipanel(app.UIFigure);
            app.BendingenergyPanel.Title = 'Bending energy';
            app.BendingSwitch = uiswitch(app.BendingenergyPanel, 'slider');
            app.BendingSwitch.ValueChangedFcn = createCallbackFcn(app, @BendingSwitchValueChanged, true);
            app.lambdaBendEditFieldLabel = uilabel(app.BendingenergyPanel);
            app.lambdaBendEditFieldLabel.Enable = 'off';
            app.lambdaBendEditFieldLabel.Text = 'lambdaBend';
            app.lambdaBendEditField = uieditfield(app.BendingenergyPanel, 'numeric');
            app.lambdaBendEditField.Limits = [0 Inf];
            app.lambdaBendEditField.Enable = 'off';
            app.lambdaBendEditField.Value = 0.01;
            app.BendingAreaDependentCheckBox = uicheckbox(app.BendingenergyPanel);
            app.BendingAreaDependentCheckBox.Enable = 'off';
            app.BendingAreaDependentCheckBox.Text = 'BendingAreaDependent';
            app.BendingAreaDependentCheckBox.Value = true;
            app.EnergybarrierPanel = uipanel(app.UIFigure);
            app.EnergybarrierPanel.Title = 'Energy barrier';
            app.EnergyBarrierSwitch = uiswitch(app.EnergybarrierPanel, 'slider');
            app.EnergyBarrierSwitch.ValueChangedFcn = createCallbackFcn(app, @EnergyBarrierSwitchValueChanged, true);
            app.EnergyBarrierSwitch.Value = 'On';
            app.lambdaBEditFieldLabel = uilabel(app.EnergybarrierPanel);
            app.lambdaBEditFieldLabel.Text = 'lambdaB';
            app.lambdaBEditField = uieditfield(app.EnergybarrierPanel, 'numeric');
            app.lambdaBEditField.Value = 5;
            app.BetaEditFieldLabel = uilabel(app.EnergybarrierPanel);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.EnergybarrierPanel, 'numeric');
            app.BetaEditField.Value = 1;
            app.BarrierTri0EditFieldLabel = uilabel(app.EnergybarrierPanel);
            app.BarrierTri0EditFieldLabel.Text = 'BarrierTri0';
            app.BarrierTri0EditField = uieditfield(app.EnergybarrierPanel, 'numeric');
            app.BarrierTri0EditField.ValueDisplayFormat = '%e';
            app.BarrierTri0EditField.Value = 0.0015;
            app.RemodellingPanel = uipanel(app.UIFigure);
            app.RemodellingPanel.Title = 'Remodelling';
            app.RemodellingSwitch = uiswitch(app.RemodellingPanel, 'slider');
            app.RemodellingSwitch.ValueChangedFcn = createCallbackFcn(app, @RemodellingSwitchValueChanged, true);
            app.RemodelTolEditFieldLabel = uilabel(app.RemodellingPanel);
            app.RemodelTolEditFieldLabel.Enable = 'off';
            app.RemodelTolEditFieldLabel.Text = 'RemodelTol';
            app.RemodelTolEditField = uieditfield(app.RemodellingPanel, 'numeric');
            app.RemodelTolEditField.Enable = 'off';
            app.RemodelTolEditField.Value = 5e-07;
            app.RemodelingFrequencyEditFieldLabel = uilabel(app.RemodellingPanel);
            app.RemodelingFrequencyEditFieldLabel.Enable = 'off';
            app.RemodelingFrequencyEditFieldLabel.Text = 'RemodelingFrequency';
            app.RemodelingFrequencyEditField = uieditfield(app.RemodellingPanel, 'numeric');
            app.RemodelingFrequencyEditField.Enable = 'off';
            app.RemodelingFrequencyEditField.Value = 2;
            app.CellAblationPanel = uipanel(app.UIFigure);
            app.CellAblationPanel.Title = 'Cell Ablation';
            app.ContractilityenergyPanel = uipanel(app.CellAblationPanel);
            app.ContractilityenergyPanel.Title = 'Contractility energy';
            app.ContractilitySwitch = uiswitch(app.ContractilityenergyPanel, 'slider');
            app.ContractilitySwitch.ValueChangedFcn = createCallbackFcn(app, @ContractilitySwitchValueChanged, true);
            app.ContractilitySwitch.Enable = 'off';
            app.cContractilityEditFieldLabel = uilabel(app.ContractilityenergyPanel);
            app.cContractilityEditFieldLabel.Enable = 'off';
            app.cContractilityEditFieldLabel.Text = 'cContractility';
            app.cContractilityEditField = uieditfield(app.ContractilityenergyPanel, 'numeric');
            app.cContractilityEditField.Limits = [0 Inf];
            app.cContractilityEditField.Enable = 'off';
            app.cContractilityEditField.Value = 0.02;
            app.ContractilitytypeDropDownLabel = uilabel(app.ContractilityenergyPanel);
            app.ContractilitytypeDropDownLabel.Enable = 'off';
            app.ContractilitytypeDropDownLabel.Text = 'Contractility type';
            app.ContractilitytypeDropDown = uidropdown(app.ContractilityenergyPanel);
            app.ContractilitytypeDropDown.Items = {'Edge length', 'Edge length and angle', 'Edge length, angle and adjacent triangles'};
            app.ContractilitytypeDropDown.Enable = 'off';
            app.ContractilitytypeDropDown.Value = 'Edge length';
            app.AblationSwitch = uiswitch(app.CellAblationPanel, 'slider');
            app.AblationSwitch.ValueChangedFcn = createCallbackFcn(app, @AblationSwitchValueChanged, true);
            app.TAblationEditFieldLabel = uilabel(app.CellAblationPanel);
            app.TAblationEditFieldLabel.Enable = 'off';
            app.TAblationEditFieldLabel.Text = 'TAblation';
            app.TAblationEditField = uieditfield(app.CellAblationPanel, 'numeric');
            app.TAblationEditField.Limits = [1 Inf];
            app.TAblationEditField.Enable = 'off';
            app.TAblationEditField.Value = 2;
            app.cellsToAblateEditFieldLabel = uilabel(app.CellAblationPanel);
            app.cellsToAblateEditFieldLabel.Enable = 'off';
            app.cellsToAblateEditFieldLabel.Text = 'cellsToAblate';
            app.cellsToAblateEditField = uieditfield(app.CellAblationPanel, 'numeric');
            app.cellsToAblateEditField.Limits = [1 Inf];
            app.cellsToAblateEditField.Enable = 'off';
            app.cellsToAblateEditField.Value = 2;
            app.ViscosityPanel = uipanel(app.UIFigure);
            app.ViscosityPanel.Title = 'Viscosity';
            app.nuEditFieldLabel = uilabel(app.ViscosityPanel);
            app.nuEditFieldLabel.Text = 'nu';
            app.nuEditField = uieditfield(app.ViscosityPanel, 'numeric');
            app.nuEditField.Value = 0.05;
        end
    end

    methods (Access = public)

        % Construct app
        function app = window

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end